classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ViewSeparateImagesButton  matlab.ui.control.Button
        ViewStackedImagesButton   matlab.ui.control.Button
        ImageSelectorSliderLabel  matlab.ui.control.Label
        ImageSelectorSlider       matlab.ui.control.Slider
        Image                     matlab.ui.control.Image
        Image1Label               matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        Image2Label               matlab.ui.control.Label
        Image3                    matlab.ui.control.Image
        Image3Label               matlab.ui.control.Label
        Image4                    matlab.ui.control.Image
        Image4Label               matlab.ui.control.Label
        ToselectacertainImageusesliderbelowLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ViewSeparateImagesButton
        function ViewSeparateImagesButtonPushed(app, event)
a = dicomread('IM-0001-0001.dcm');
b = dicomread('IM-0001-0002.dcm');
c = dicomread('IM-0001-0003.dcm');
d = dicomread('IM-0001-0004.dcm');

figure(1)
a_imadjust = imadjust(a);
a_histeq = histeq(a);
a_adapthisteq = adapthisteq(a);
imshow(a_imadjust)

figure(2)
b_imadjust = imadjust(b);
b_histeq = histeq(b);
b_adapthisteq = adapthisteq(b);
imshow(b_imadjust)

figure(3)
c_imadjust = imadjust(c);
c_histeq = histeq(c);
c_adapthisteq = adapthisteq(c);
imshow(c_imadjust)

figure(4)
d_imadjust = imadjust(d);
d_histeq = histeq(d);
d_adapthisteq = adapthisteq(d);
imshow(d_imadjust)
        end

        % Button pushed function: ViewStackedImagesButton
        function ViewStackedImagesButtonPushed(app, event)
a = dicomread('IM-0001-0001.dcm');
b = dicomread('IM-0001-0002.dcm');
c = dicomread('IM-0001-0003.dcm');
d = dicomread('IM-0001-0004.dcm');

D = cat(3,a,b,c,d);
figure
colormap gray
contourslice(D,[],[],[1,2,3,4,27],15);
view(3)
axis tight

        end

        % Value changed function: ImageSelectorSlider
        function ImageSelectorSliderValueChanged(app, event)
            value = app.ImageSelectorSlider.Value;
a = dicomread('IM-0001-0001.dcm');
b = dicomread('IM-0001-0002.dcm');
c = dicomread('IM-0001-0003.dcm');
d = dicomread('IM-0001-0004.dcm');
if value>.5 & value<1.5;
    close all
    a_imadjust = imadjust(a);
    a_histeq = histeq(a);
    a_adapthisteq = adapthisteq(a);    
    imshow(a_imadjust);
    
    axis on;
    title('Image 1');
    set(gcf,'Position',get(0,'Screensize')); %Maximizing the figure
    message =sprintf('(Image 1) Left click the mouse to begin drawing.\n Stop holding the mouse button to finish');
    uiwait(msgbox(message));
    ROI_a = imfreehand; %Line of code from Andrew that does the drawing
    binaryImage = ROI_a.createMask; %This is creating a mask from the ROI
    xy=ROI_a.getPosition;
    
    subplot(2,3,1);
    imshow(a_imadjust,[]);
    axis on;
    drawnow;
    title('Image 1');
    
    subplot(2,3,2);
    imshow(binaryImage);
    axis on;
    title('Binary mask of the Heart image');
    

    numberofPixels1=sum(binaryImage(:))
    
    structBoundaries = bwboundaries(binaryImage);
    xy= structBoundaries{1}; % This gives us a n by 2 arry of x and y coordinates
    x = xy(:,2); % Column of interest
    y = xy(:,1); % row of interest
    subplot(2,3,1); % plotting over the original image
    hold on;
    plot(x,y,'LineWidth',2);
    drawnow; % forcing matlab to draw it asap
    
    burnedImage = a_imadjust;
    burnedImage(binaryImage) = 255;
   
    subplot(2,3,3);
    imshow(burnedImage);
    axis on;
    caption = sprintf('New image with mask \n burned into image');
    title(caption);
    
    blackMaskedImage=a_imadjust;
    blackMaskedImage(~binaryImage) = 0;
    subplot(2,3,4);
    imshow(blackMaskedImage);
    axis on;
    title('Masked Outside Region');


    insideMasked = a_imadjust;
    insideMasked(binaryImage) = 0;
    subplot(2,3,5);
    imshow(insideMasked);
    axis on;
    title('Masked inside region');
    
    leftColumn = min(x);
    rightColumn = max(x);
    topLine = min(y);
    bottomLine = max(y);
    width = rightColumn - leftColumn + 1;
    height = bottomLine - topLine + 1;
    croppedImage = imcrop(blackMaskedImage,[leftColumn,topLine,width,height]);
    
    subplot(2,3,6);
    imshow(croppedImage);
    axis 'on'
    title('Cropped Image');
    
  
    volume1=(.625^2)*4*numberofPixels1;
    save volume1
    volume2=0;
    volume3=0;
    volume4=0;
    load volume2.mat
    load volume3.mat
    load volume4.mat
        if volume2==0 & volume3==0 & volume4==0        % only 1
            volumetot=volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
            msgbox(message);
        elseif volume2>0 & volume3==0 & volume4==0            % 1 and 2
            volumetot=volume1+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
            msgbox(message);
        elseif volume3>0 & volume2==0 & volume4==0        % 1 and 3
            volumetot=volume3+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3==0 & volume2==0        % 1 and 4
            volumetot=volume4+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3>0 & volume2==0        % 1,3,4
            volumetot=volume4+volume3+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3>0 & volume2>0        % 1,2,3,4
            volumetot=volume4+volume3+volume2+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
            msgbox(message);
        elseif volume4==0 & volume3>0 & volume2>0        % 1,2,3
            volumetot=volume1+volume3+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3==0 & volume2>0        %1,2,4
            volumetot=volume1+volume4+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
            msgbox(message);
        end

elseif value>1.5 & value<2.5;
    close all
    b_imadjust = imadjust(b);
    b_histeq = histeq(b);
    b_adapthisteq = adapthisteq(b);    
    imshow(b_imadjust);
    
    axis on;
    title('Image 2');
    set(gcf,'Position',get(0,'Screensize')); %Maximizing the figure
    %Now asking user to draw on the image, after which we will apply a mask
    
    message =sprintf('(Image 2) Left click the mouse to begin drawing.\n Stop holding the mouse button to finish');
    uiwait(msgbox(message));
    ROI_b = imfreehand; %Line of code from Andrew that does the drawing
    binaryImage = ROI_b.createMask; %This is creating a mask from the ROI
    %Mask is basically a binary image of the ROI
    xy=ROI_b.getPosition;
    %using subplots to show more images
    
    subplot(2,3,1);
    imshow(b_imadjust,[]);
    axis on;
    drawnow;
    title('Image 2');
    
    % Now we display the mask that drawn by user
    subplot(2,3,2);
    imshow(binaryImage);
    axis on;
    title('Binary mask of the Heart image');
    
    
    numberofPixels1=sum(binaryImage(:))
    
    % Getting the coordinates of the boundary of MRI region selected by the
    % user
    structBoundaries = bwboundaries(binaryImage);
    xy= structBoundaries{1}; % This gives us a n by 2 arry of x and y coordinates
    x = xy(:,2); % Column of interest
    y = xy(:,1); % row of interest
    subplot(2,3,1); % plotting over the original image
    hold on;
    plot(x,y,'LineWidth',2);
    drawnow; % forcing matlab to draw it asap
    
    % Now we are burning line into image by setting it to 255 wherever the mask
    % is true
    burnedImage = b_imadjust;
    burnedImage(binaryImage) = 255;
    
    % Here we are displaying the image with the mask burned in
    subplot(2,3,3);
    imshow(burnedImage);
    axis on;
    caption = sprintf('New image with mask \n burned into image');
    title(caption);
    
    %Now keeping only the part of the image that is inside the mask
    blackMaskedImage=b_imadjust;
    blackMaskedImage(~binaryImage) = 0;
    subplot(2,3,4);
    imshow(blackMaskedImage);
    axis on;
    title('Masked Outside Region');


    % Blackening inside the region
    insideMasked = b_imadjust;
    insideMasked(binaryImage) = 0;
    subplot(2,3,5);
    imshow(insideMasked);
    axis on;
    title('Masked inside region');
    
    % Cropping the image
    leftColumn = min(x);
    rightColumn = max(x);
    topLine = min(y);
    bottomLine = max(y);
    width = rightColumn - leftColumn + 1;
    height = bottomLine - topLine + 1;
    croppedImage = imcrop(blackMaskedImage,[leftColumn,topLine,width,height]);
   
    % displaying cropped image
    subplot(2,3,6);
    imshow(croppedImage);
    axis 'on'
    title('Cropped Image');
   
    % report the results of the calculation
    volume2=(.625^2)*4*numberofPixels1;
    save volume2
    volume1=0;
    volume3=0;
    volume4=0;
    load volume1.mat
    load volume3.mat
    load volume4.mat
         if volume1==0 & volume3==0 & volume4==0        % only 2
            volumetot=volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
            msgbox(message);
         elseif volume1>0 & volume3==0 & volume4==0            % 1 and 2
            volumetot=volume1+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
            msgbox(message);
        elseif volume3>0 & volume1==0 & volume4==0        % 2 and 3
            volumetot=volume3+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3==0 & volume1==0        % 2 and 4
            volumetot=volume4+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3>0 & volume1==0        % 2,3,4
            volumetot=volume4+volume3+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3>0 & volume1>0        % 1,2,3,4
            volumetot=volume4+volume3+volume2+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
            msgbox(message);
        elseif volume4==0 & volume3>0 & volume1>0        % 1,2,3
            volumetot=volume2+volume3+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3==0 & volume1>0        %1,2,4
            volumetot=volume1+volume4+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
            msgbox(message);
        end
    
    
elseif value>2.5 & value<3.5;
    close all
    c_imadjust = imadjust(c);
    c_histeq = histeq(c);
    c_adapthisteq = adapthisteq(c);
    imshow(c_imadjust);
    title('Image 3');
    set(gcf,'Position',get(0,'Screensize')); %Maximizing the figure
    
    message =sprintf('(Image 3) Left click the mouse to begin drawing.\n Stop holding the mouse button to finish');
    uiwait(msgbox(message));
    ROI_c = imfreehand; %Line of code from Andrew that does the drawing
    binaryImage = ROI_c.createMask; %This is creating a mask from the ROI
    xy=ROI_c.getPosition;
    
    subplot(2,3,1);
    imshow(c_imadjust,[]);
    axis on;
    drawnow;
    title('Image 3');
    
    subplot(2,3,2);
    imshow(binaryImage);
    axis on;
    title('Binary mask of the Heart image');
    
    numberofPixels1=sum(binaryImage(:))
    
    structBoundaries = bwboundaries(binaryImage);
    xy= structBoundaries{1}; % This gives us a n by 2 arry of x and y coordinates
    x = xy(:,2); % Column of interest
    y = xy(:,1); % row of interest
    subplot(2,3,1); % plotting over the original image
    hold on;
    plot(x,y,'LineWidth',2);
    drawnow; % forcing matlab to draw it asap
    
    burnedImage = c_imadjust;
    burnedImage(binaryImage) = 255;
    
    subplot(2,3,3);
    imshow(burnedImage);
    axis on;
    caption = sprintf('New image with mask \n burned into image');
    title(caption);
    
    blackMaskedImage=c_imadjust;
    blackMaskedImage(~binaryImage) = 0;
    subplot(2,3,4);
    imshow(blackMaskedImage);
    axis on;
    title('Masked Outside Region');
    meanGL=mean(blackMaskedImage(binaryImage));
    sdGL = std(double(blackMaskedImage(binaryImage)));
    
    insideMasked = c_imadjust;
    insideMasked(binaryImage) = 0;
    subplot(2,3,5);
    imshow(insideMasked);
    axis on;
    title('Masked inside region');
    
    leftColumn = min(x);
    rightColumn = max(x);
    topLine = min(y);
    bottomLine = max(y);
    width = rightColumn - leftColumn + 1;
    height = bottomLine - topLine + 1;
    croppedImage = imcrop(blackMaskedImage,[leftColumn,topLine,width,height]);
    
    subplot(2,3,6);
    imshow(croppedImage);
    axis 'on'
    title('Cropped Image');
    
    
    volume3=(.625^2)*4*numberofPixels1;
    save volume3
    volume1=0;
    volume2=0;
    volume4=0;
    load volume1.mat
    load volume2.mat
    load volume4.mat
        if volume2==0 & volume1==0 & volume4==0        % only 3
            volumetot=volume3;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
            msgbox(message);
        elseif volume2>0 & volume1==0 & volume4==0            % 3 and 2
            volumetot=volume3+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
            msgbox(message);
        elseif volume1>0 & volume2==0 & volume4==0        % 1 and 3
            volumetot=volume3+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
            msgbox(message);
        elseif volume4>0 & volume1==0 & volume2==0        % 3 and 4
            volumetot=volume4+volume3;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
            msgbox(message);
        elseif volume4>0 & volume1>0 & volume2==0        % 1,3,4
            volumetot=volume4+volume3+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
            msgbox(message);
        elseif volume4>0 & volume3>0 & volume2>0        % 1,2,3,4
            volumetot=volume4+volume3+volume2+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
            msgbox(message);
        elseif volume4==0 & volume1>0 & volume2>0        % 1,2,3
            volumetot=volume1+volume3+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
            msgbox(message);
        elseif volume4>0 & volume1==0 & volume2>0        %3,2,4
            volumetot=volume3+volume4+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
            msgbox(message);
        end

elseif value>3.5 & value<4.5;
    close all
    d_imadjust = imadjust(d);
    d_histeq = histeq(d);
    d_adapthisteq = adapthisteq(d);
    imshow(d_imadjust);

    axis on;
    title('Image 4');
    set(gcf,'Position',get(0,'Screensize')); %Maximizing the figure
    
    message =sprintf('(Image 4)Left click the mouse to begin drawing.\n Stop holding the mouse button to finish');
    uiwait(msgbox(message));
    ROI_d = imfreehand; %Line of code from Andrew that does the drawing
    binaryImage = ROI_d.createMask; %This is creating a mask from the ROI
    xy=ROI_d.getPosition;
    
    subplot(2,3,1);
    imshow(d_imadjust,[]);
    axis on;
    drawnow;
    title('Image 4');
    
    subplot(2,3,2);
    imshow(binaryImage);
    axis on;
    title('Binary mask of the Heart image');
    
    
    numberofPixels1=sum(binaryImage(:))
    
    structBoundaries = bwboundaries(binaryImage);
    xy= structBoundaries{1}; % This gives us a n by 2 arry of x and y coordinates
    x = xy(:,2); % Column of interest
    y = xy(:,1); % row of interest
    subplot(2,3,1); % plotting over the original image
    hold on;
    plot(x,y,'LineWidth',2);
    drawnow; % forcing matlab to draw it asap
    
    burnedImage = d_imadjust;
    burnedImage(binaryImage) = 255;
    
    subplot(2,3,3);
    imshow(burnedImage);
    axis on;
    caption = sprintf('New image with mask \n burned into image');
    title(caption);
    
    blackMaskedImage=d_imadjust;
    blackMaskedImage(~binaryImage) = 0;
    subplot(2,3,4);
    imshow(blackMaskedImage);
    axis on;
    title('Masked Outside Region');
    
    
    insideMasked = d_imadjust;
    insideMasked(binaryImage) = 0;
    subplot(2,3,5);
    imshow(insideMasked);
    axis on;
    title('Masked inside region');
    
    leftColumn = min(x);
    rightColumn = max(x);
    topLine = min(y);
    bottomLine = max(y);
    width = rightColumn - leftColumn + 1;
    height = bottomLine - topLine + 1;
    croppedImage = imcrop(blackMaskedImage,[leftColumn,topLine,width,height]);
    
    subplot(2,3,6);
    imshow(croppedImage);
    axis 'on'
    title('Cropped Image');
    
    
    volume4=(.625^2)*4*numberofPixels1;
    save volume4
    volume1=0;
    volume2=0;
    volume3=0;
    load volume1.mat
    load volume2.mat
    load volume3.mat
        if volume2==0 & volume1==0 & volume3==0        % only 4
            volumetot=volume4;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
            msgbox(message);
        elseif volume2>0 & volume1==0 & volume3==0     % 4 and 2
            volumetot=volume4+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
            msgbox(message);
        elseif volume1>0 & volume2==0 & volume3==0        % 4 and 1
            volumetot=volume1+volume4;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
            msgbox(message);
        elseif volume3>0 & volume1==0 & volume2==0        % 3 and 4
            volumetot=volume4+volume3;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
            msgbox(message);
        elseif volume3>0 & volume1>0 & volume2==0        % 1,3,4
            volumetot=volume4+volume3+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
            msgbox(message);
        elseif volume1>0 & volume3>0 & volume2>0        % 1,2,3,4
            volumetot=volume4+volume3+volume2+volume1;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
            msgbox(message);
        elseif volume3==0 & volume1>0 & volume2>0        % 1,2,4
            volumetot=volume1+volume4+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
            msgbox(message);
        elseif volume4>0 & volume1==0 & volume2>0        %3,2,4
            volumetot=volume3+volume4+volume2;
            message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
            msgbox(message);
        end
end
    
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
a = dicomread('IM-0001-0001.dcm');
a_imadjust = imadjust(a);
a_histeq = histeq(a);
a_adapthisteq = adapthisteq(a);
imshow(a_imadjust)
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
b = dicomread('IM-0001-0002.dcm');
b_imadjust = imadjust(b);
b_histeq = histeq(b);
b_adapthisteq = adapthisteq(b);
imshow(b_imadjust)            
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
c = dicomread('IM-0001-0003.dcm');
c_imadjust = imadjust(c);
c_histeq = histeq(c);
c_adapthisteq = adapthisteq(c);
imshow(c_imadjust)                    
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
d = dicomread('IM-0001-0004.dcm');
d_imadjust = imadjust(d);
d_histeq = histeq(d);
d_adapthisteq = adapthisteq(d);
imshow(d_imadjust)                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ViewSeparateImagesButton = uibutton(app.UIFigure, 'push');
            app.ViewSeparateImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSeparateImagesButtonPushed, true);
            app.ViewSeparateImagesButton.Text = 'View Separate Images';
            app.ViewStackedImagesButton = uibutton(app.UIFigure, 'push');
            app.ViewStackedImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewStackedImagesButtonPushed, true);
            app.ViewStackedImagesButton.Text = 'View Stacked Images';
            app.ImageSelectorSliderLabel = uilabel(app.UIFigure);
            app.ImageSelectorSliderLabel.Text = 'Image Selector';
            app.ImageSelectorSlider = uislider(app.UIFigure);
            app.ImageSelectorSlider.Limits = [0 4];
            app.ImageSelectorSlider.MajorTicks = [0 1 2 3 4];
            app.ImageSelectorSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectorSliderValueChanged, true);
            app.ImageSelectorSlider.MinorTicks = [1 2 3 4];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'HU_dcmImage1.png';
            app.Image1Label = uilabel(app.UIFigure);
            app.Image1Label.Text = ' Image 1';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'HU_dcmImage2.png';
            app.Image2Label = uilabel(app.UIFigure);
            app.Image2Label.Text = ' Image 2';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = 'HU_dcmImage3.png';
            app.Image3Label = uilabel(app.UIFigure);
            app.Image3Label.Text = ' Image 3';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'HU_dcmImage4.png';
            app.Image4Label = uilabel(app.UIFigure);
            app.Image4Label.Text = ' Image 4';
            app.ToselectacertainImageusesliderbelowLabel = uilabel(app.UIFigure);
            app.ToselectacertainImageusesliderbelowLabel.Text = ' To select a certain Image, use slider below: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end