classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ViewSeparateImagesButton  matlab.ui.control.Button
        ToselectacertainImageusesliderbelowLabel  matlab.ui.control.Label
        ImageSelectorLabel        matlab.ui.control.Label
        Slider_2Label             matlab.ui.control.Label
        Slider_2                  matlab.ui.control.Slider
        BrowseButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        alldata;
        allinfos;% Description
        slider; % Description
        tick;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, a)
%             [files,folder] = uigetfile('*.dcm','MultiSelect','on');
%             long = length(files);
%             if long == 16;
%                 long = 1;
%             else
%             long == long;
%              end
%             app.Slider_2.Limits = [0 long];
%             app.Slider_2.MajorTicks=[0:1:long];
%             app.Slider_2.MinorTicks=[0 long];
%             if ~iscell(files)
%                 files={files};
%             end
%             for ii=1:length(files)
%                 infos(ii)=dicominfo([folder,filesep,files{ii}]);
%                 data(:,:,ii)=dicomread([folder,filesep,files{ii}]);
%                 slider(:,:,ii)=[0 ii]
%             end
%             app.allinfos=infos;
%             app.alldata=data;
%             app.slider=slider;
% 
%           
%           
        end

        % Button pushed function: ViewSeparateImagesButton
        function ViewSeparateImagesButtonPushed(app, event)
I=app.alldata;
figure(1)
a_imadjust=imadjust(I(:,:,1))
imshow(a_imadjust)
figure(2)
b_imadjust=imadjust(I(:,:,2))
imshow(b_imadjust)
figure(3)
c_imadjust=imadjust(I(:,:,3))
imshow(c_imadjust)
figure(4)
d_imadjust=imadjust(I(:,:,4))
imshow(d_imadjust)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;

if value>.5 & value<1.5;
    close all
    I=app.alldata;
    a_imadjust=imadjust(I(:,:,1)) 
    imshow(a_imadjust);
    
    axis on;
    title('Image 1');
    set(gcf,'Position',get(0,'Screensize')); %Maximizing the figure
    message =sprintf('(Image 1) Left click the mouse to begin drawing.\n Stop holding the mouse button to finish');
    uiwait(msgbox(message));
    ROI_a = imfreehand; %Line of code from Andrew that does the drawing
    binaryImage = ROI_a.createMask; %This is creating a mask from the ROI
    xy=ROI_a.getPosition;
    
    subplot(2,3,1);
    imshow(a_imadjust,[]);
    axis on;
    drawnow;
    title('Image 1');
    
    subplot(2,3,2);
    imshow(binaryImage);
    axis on;
    title('Binary mask of the Heart image');
    

    numberofPixels1=sum(binaryImage(:))
    
    structBoundaries = bwboundaries(binaryImage);
    xy= structBoundaries{1}; % This gives us a n by 2 arry of x and y coordinates
    x = xy(:,2); % Column of interest
    y = xy(:,1); % row of interest
    subplot(2,3,1); % plotting over the original image
    hold on;
    plot(x,y,'LineWidth',2);
    drawnow; % forcing matlab to draw it asap
    
    burnedImage = a_imadjust;
    burnedImage(binaryImage) = 255;
   
    subplot(2,3,3);
    imshow(burnedImage);
    axis on;
    caption = sprintf('New image with mask \n burned into image');
    title(caption);
    
    blackMaskedImage=a_imadjust;
    blackMaskedImage(~binaryImage) = 0;
    subplot(2,3,4);
    imshow(blackMaskedImage);
    axis on;
    title('Masked Outside Region');


    insideMasked = a_imadjust;
    insideMasked(binaryImage) = 0;
    subplot(2,3,5);
    imshow(insideMasked);
    axis on;
    title('Masked inside region');
    
    leftColumn = min(x);
    rightColumn = max(x);
    topLine = min(y);
    bottomLine = max(y);
    width = rightColumn - leftColumn + 1;
    height = bottomLine - topLine + 1;
    croppedImage = imcrop(blackMaskedImage,[leftColumn,topLine,width,height]);
    
    subplot(2,3,6);
    imshow(croppedImage);
    axis 'on'
    title('Cropped Image');
    
  
    volume1=(.625^2)*4*numberofPixels1;
    save volume1
    volumetot=volume1;
    message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
    msgbox(message);
%         if volume2==0 & volume3==0 & volume4==0        % only 1
%             volumetot=volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
%             msgbox(message);
%         elseif volume2>0 & volume3==0 & volume4==0            % 1 and 2
%             volumetot=volume1+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
%             msgbox(message);
%         elseif volume3>0 & volume2==0 & volume4==0        % 1 and 3
%             volumetot=volume3+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3==0 & volume2==0        % 1 and 4
%             volumetot=volume4+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3>0 & volume2==0        % 1,3,4
%             volumetot=volume4+volume3+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3>0 & volume2>0        % 1,2,3,4
%             volumetot=volume4+volume3+volume2+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
%             msgbox(message);
%         elseif volume4==0 & volume3>0 & volume2>0        % 1,2,3
%             volumetot=volume1+volume3+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3==0 & volume2>0        %1,2,4
%             volumetot=volume1+volume4+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume1,volumetot);
%             msgbox(message);
%         end

elseif value>1.5 & value<2.5;
    close all
    I=app.alldata;
    b_imadjust=imadjust(I(:,:,2)) 
    imshow(b_imadjust);
    
    axis on;
    title('Image 2');
    set(gcf,'Position',get(0,'Screensize')); %Maximizing the figure
    %Now asking user to draw on the image, after which we will apply a mask
    
    message =sprintf('(Image 2) Left click the mouse to begin drawing.\n Stop holding the mouse button to finish');
    uiwait(msgbox(message));
    ROI_b = imfreehand; %Line of code from Andrew that does the drawing
    binaryImage = ROI_b.createMask; %This is creating a mask from the ROI
    %Mask is basically a binary image of the ROI
    xy=ROI_b.getPosition;
    %using subplots to show more images
    
    subplot(2,3,1);
    imshow(b_imadjust,[]);
    axis on;
    drawnow;
    title('Image 2');
    
    % Now we display the mask that drawn by user
    subplot(2,3,2);
    imshow(binaryImage);
    axis on;
    title('Binary mask of the Heart image');
    
    
    numberofPixels1=sum(binaryImage(:))
    
    % Getting the coordinates of the boundary of MRI region selected by the
    % user
    structBoundaries = bwboundaries(binaryImage);
    xy= structBoundaries{1}; % This gives us a n by 2 arry of x and y coordinates
    x = xy(:,2); % Column of interest
    y = xy(:,1); % row of interest
    subplot(2,3,1); % plotting over the original image
    hold on;
    plot(x,y,'LineWidth',2);
    drawnow; % forcing matlab to draw it asap
    
    % Now we are burning line into image by setting it to 255 wherever the mask
    % is true
    burnedImage = b_imadjust;
    burnedImage(binaryImage) = 255;
    
    % Here we are displaying the image with the mask burned in
    subplot(2,3,3);
    imshow(burnedImage);
    axis on;
    caption = sprintf('New image with mask \n burned into image');
    title(caption);
    
    %Now keeping only the part of the image that is inside the mask
    blackMaskedImage=b_imadjust;
    blackMaskedImage(~binaryImage) = 0;
    subplot(2,3,4);
    imshow(blackMaskedImage);
    axis on;
    title('Masked Outside Region');


    % Blackening inside the region
    insideMasked = b_imadjust;
    insideMasked(binaryImage) = 0;
    subplot(2,3,5);
    imshow(insideMasked);
    axis on;
    title('Masked inside region');
    
    % Cropping the image
    leftColumn = min(x);
    rightColumn = max(x);
    topLine = min(y);
    bottomLine = max(y);
    width = rightColumn - leftColumn + 1;
    height = bottomLine - topLine + 1;
    croppedImage = imcrop(blackMaskedImage,[leftColumn,topLine,width,height]);
   
    % displaying cropped image
    subplot(2,3,6);
    imshow(croppedImage);
    axis 'on'
    title('Cropped Image');
   
    % report the results of the calculation
    volume2=(.625^2)*4*numberofPixels1;
    save volume2
    load volume1.mat
    volumetot=volume1+volume2;
    message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
    msgbox(message);
    
%          if volume1==0 & volume3==0 & volume4==0        % only 2
%             volumetot=volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
%             msgbox(message);
%          elseif volume1>0 & volume3==0 & volume4==0            % 1 and 2
%             volumetot=volume1+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
%             msgbox(message);
%         elseif volume3>0 & volume1==0 & volume4==0        % 2 and 3
%             volumetot=volume3+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3==0 & volume1==0        % 2 and 4
%             volumetot=volume4+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3>0 & volume1==0        % 2,3,4
%             volumetot=volume4+volume3+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3>0 & volume1>0        % 1,2,3,4
%             volumetot=volume4+volume3+volume2+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
%             msgbox(message);
%         elseif volume4==0 & volume3>0 & volume1>0        % 1,2,3
%             volumetot=volume2+volume3+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3==0 & volume1>0        %1,2,4
%             volumetot=volume1+volume4+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume2,volumetot);
%             msgbox(message);
%         end
%     
%     

elseif value>2.5 & value<3.5;
    close all
    I=app.alldata;
    c_imadjust=imadjust(I(:,:,3)) 
    imshow(c_imadjust);
    
    title('Image 3');
    set(gcf,'Position',get(0,'Screensize')); %Maximizing the figure
    
    message =sprintf('(Image 3) Left click the mouse to begin drawing.\n Stop holding the mouse button to finish');
    uiwait(msgbox(message));
    ROI_c = imfreehand; %Line of code from Andrew that does the drawing
    binaryImage = ROI_c.createMask; %This is creating a mask from the ROI
    xy=ROI_c.getPosition;
    
    subplot(2,3,1);
    imshow(c_imadjust,[]);
    axis on;
    drawnow;
    title('Image 3');
    
    subplot(2,3,2);
    imshow(binaryImage);
    axis on;
    title('Binary mask of the Heart image');
    
    numberofPixels1=sum(binaryImage(:))
    
    structBoundaries = bwboundaries(binaryImage);
    xy= structBoundaries{1}; % This gives us a n by 2 arry of x and y coordinates
    x = xy(:,2); % Column of interest
    y = xy(:,1); % row of interest
    subplot(2,3,1); % plotting over the original image
    hold on;
    plot(x,y,'LineWidth',2);
    drawnow; % forcing matlab to draw it asap
    
    burnedImage = c_imadjust;
    burnedImage(binaryImage) = 255;
    
    subplot(2,3,3);
    imshow(burnedImage);
    axis on;
    caption = sprintf('New image with mask \n burned into image');
    title(caption);
    
    blackMaskedImage=c_imadjust;
    blackMaskedImage(~binaryImage) = 0;
    subplot(2,3,4);
    imshow(blackMaskedImage);
    axis on;
    title('Masked Outside Region');
    meanGL=mean(blackMaskedImage(binaryImage));
    sdGL = std(double(blackMaskedImage(binaryImage)));
    
    insideMasked = c_imadjust;
    insideMasked(binaryImage) = 0;
    subplot(2,3,5);
    imshow(insideMasked);
    axis on;
    title('Masked inside region');
    
    leftColumn = min(x);
    rightColumn = max(x);
    topLine = min(y);
    bottomLine = max(y);
    width = rightColumn - leftColumn + 1;
    height = bottomLine - topLine + 1;
    croppedImage = imcrop(blackMaskedImage,[leftColumn,topLine,width,height]);
    
    subplot(2,3,6);
    imshow(croppedImage);
    axis 'on'
    title('Cropped Image');
    
    
    volume3=(.625^2)*4*numberofPixels1;
    save volume3
    load volume1.mat
    load volume2.mat
    volumetot=volume1+volume2+volume3;
    message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
    msgbox(message);
    
%         if volume2==0 & volume1==0 & volume4==0        % only 3
%             volumetot=volume3;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
%             msgbox(message);
%         elseif volume2>0 & volume1==0 & volume4==0            % 3 and 2
%             volumetot=volume3+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
%             msgbox(message);
%         elseif volume1>0 & volume2==0 & volume4==0        % 1 and 3
%             volumetot=volume3+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume1==0 & volume2==0        % 3 and 4
%             volumetot=volume4+volume3;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume1>0 & volume2==0        % 1,3,4
%             volumetot=volume4+volume3+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume3>0 & volume2>0        % 1,2,3,4
%             volumetot=volume4+volume3+volume2+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
%             msgbox(message);
%         elseif volume4==0 & volume1>0 & volume2>0        % 1,2,3
%             volumetot=volume1+volume3+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume1==0 & volume2>0        %3,2,4
%             volumetot=volume3+volume4+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume3,volumetot);
%             msgbox(message);
%         end

elseif value>3.5 & value<4.5;
    close all
    I=app.alldata;
    d_imadjust=imadjust(I(:,:,4)) 
    imshow(d_imadjust);

    axis on;
    title('Image 4');
    set(gcf,'Position',get(0,'Screensize')); %Maximizing the figure
    
    message =sprintf('(Image 4)Left click the mouse to begin drawing.\n Stop holding the mouse button to finish');
    uiwait(msgbox(message));
    ROI_d = imfreehand; %Line of code from Andrew that does the drawing
    binaryImage = ROI_d.createMask; %This is creating a mask from the ROI
    xy=ROI_d.getPosition;
    
    subplot(2,3,1);
    imshow(d_imadjust,[]);
    axis on;
    drawnow;
    title('Image 4');
    
    subplot(2,3,2);
    imshow(binaryImage);
    axis on;
    title('Binary mask of the Heart image');
    
    
    numberofPixels1=sum(binaryImage(:))
    
    structBoundaries = bwboundaries(binaryImage);
    xy= structBoundaries{1}; % This gives us a n by 2 arry of x and y coordinates
    x = xy(:,2); % Column of interest
    y = xy(:,1); % row of interest
    subplot(2,3,1); % plotting over the original image
    hold on;
    plot(x,y,'LineWidth',2);
    drawnow; % forcing matlab to draw it asap
    
    burnedImage = d_imadjust;
    burnedImage(binaryImage) = 255;
    
    subplot(2,3,3);
    imshow(burnedImage);
    axis on;
    caption = sprintf('New image with mask \n burned into image');
    title(caption);
    
    blackMaskedImage=d_imadjust;
    blackMaskedImage(~binaryImage) = 0;
    subplot(2,3,4);
    imshow(blackMaskedImage);
    axis on;
    title('Masked Outside Region');
    
    
    insideMasked = d_imadjust;
    insideMasked(binaryImage) = 0;
    subplot(2,3,5);
    imshow(insideMasked);
    axis on;
    title('Masked inside region');
    
    leftColumn = min(x);
    rightColumn = max(x);
    topLine = min(y);
    bottomLine = max(y);
    width = rightColumn - leftColumn + 1;
    height = bottomLine - topLine + 1;
    croppedImage = imcrop(blackMaskedImage,[leftColumn,topLine,width,height]);
    
    subplot(2,3,6);
    imshow(croppedImage);
    axis 'on'
    title('Cropped Image');
    
    
    volume4=(.625^2)*4*numberofPixels1;
    load volume1.mat
    load volume2.mat
    load volume3.mat
    volumetot=volume1+volume2+volume3+volume4;
    message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
    msgbox(message);
%         if volume2==0 & volume1==0 & volume3==0        % only 4
%             volumetot=volume4;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
%             msgbox(message);
%         elseif volume2>0 & volume1==0 & volume3==0     % 4 and 2
%             volumetot=volume4+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
%             msgbox(message);
%         elseif volume1>0 & volume2==0 & volume3==0        % 4 and 1
%             volumetot=volume1+volume4;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
%             msgbox(message);
%         elseif volume3>0 & volume1==0 & volume2==0        % 3 and 4
%             volumetot=volume4+volume3;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
%             msgbox(message);
%         elseif volume3>0 & volume1>0 & volume2==0        % 1,3,4
%             volumetot=volume4+volume3+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
%             msgbox(message);
%         elseif volume1>0 & volume3>0 & volume2>0        % 1,2,3,4
%             volumetot=volume4+volume3+volume2+volume1;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
%             msgbox(message);
%         elseif volume3==0 & volume1>0 & volume2>0        % 1,2,4
%             volumetot=volume1+volume4+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
%             msgbox(message);
%         elseif volume4>0 & volume1==0 & volume2>0        %3,2,4
%             volumetot=volume3+volume4+volume2;
%             message=sprintf('Number of pixels=%d\nVolume of ROI=%.2fmm^3\nTotal Volume of Images selected=%.2fmm^3',numberofPixels1,volume4,volumetot);
%             msgbox(message);
%         end
end
    
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [files,folder] = uigetfile('*.dcm','MultiSelect','on');
            long = length(files);
            if long == 16;
                long = 1;
            else
            long == long;
             end
            app.Slider_2.Limits = [0 long];
            app.Slider_2.MajorTicks=[0:1:long];
            app.Slider_2.MinorTicks=[0 long];
            if ~iscell(files)
                files={files};
            end
            for ii=1:length(files)
                infos(ii)=dicominfo([folder,filesep,files{ii}]);
                data(:,:,ii)=dicomread([folder,filesep,files{ii}]);
                slider(:,:,ii)=[0 ii]
            end
            app.allinfos=infos;
            app.alldata=data;
            app.slider=slider;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ViewSeparateImagesButton = uibutton(app.UIFigure, 'push');
            app.ViewSeparateImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSeparateImagesButtonPushed, true);
            app.ViewSeparateImagesButton.Text = 'View Separate Images';
            app.ToselectacertainImageusesliderbelowLabel = uilabel(app.UIFigure);
            app.ToselectacertainImageusesliderbelowLabel.Text = ' To select a certain Image, use slider below: ';
            app.ImageSelectorLabel = uilabel(app.UIFigure);
            app.ImageSelectorLabel.Text = 'Image Selector';
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end